classdef mainCAO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulateurdebalanceUIFigure   matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SimulationTab                 matlab.ui.container.Tab
        UIAnim                        matlab.ui.control.UIAxes
        Lamp                          matlab.ui.control.Lamp
        ParamtresnumismatiqueLabel    matlab.ui.control.Label
        UICoinTable                   matlab.ui.control.Table
        UIMassAction                  matlab.ui.control.UIAxes
        SimulerButton                 matlab.ui.control.Button
        AddPanel                      matlab.ui.container.Panel
        AjouterButton                 matlab.ui.control.Button
        DeviseDropDown_2Label         matlab.ui.control.Label
        DeviseDropDown                matlab.ui.control.DropDown
        MontantLabel                  matlab.ui.control.Label
        MontantDropDown               matlab.ui.control.DropDown
        QuantitEditField_2Label       matlab.ui.control.Label
        QuantitEditField              matlab.ui.control.NumericEditField
        RemPanel                      matlab.ui.container.Panel
        RetirerButton                 matlab.ui.control.Button
        LigneDropDownLabel            matlab.ui.control.Label
        LigneDropDown                 matlab.ui.control.DropDown
        LogLabel                      matlab.ui.control.Label
        LogTextArea                   matlab.ui.control.TextArea
        ConfigurationTab              matlab.ui.container.Tab
        DimensionsdelabalancePanel    matlab.ui.container.Panel
        LongueurcmEditFieldLabel      matlab.ui.control.Label
        LongueurBalanceField          matlab.ui.control.NumericEditField
        LargeurcmEditFieldLabel       matlab.ui.control.Label
        LargeurBalanceField           matlab.ui.control.NumericEditField
        HauteurcmEditFieldLabel       matlab.ui.control.Label
        HauteurBalanceField           matlab.ui.control.NumericEditField
        UIDimension                   matlab.ui.control.UIAxes
        PositionduplateauSliderLabel  matlab.ui.control.Label
        PositionduplateauSlider       matlab.ui.control.Slider
        LameflexiblePanel             matlab.ui.container.Panel
        LongueurcmEditField_2Label    matlab.ui.control.Label
        LongueurLameField             matlab.ui.control.NumericEditField
        LargeurcmEditField_2Label     matlab.ui.control.Label
        LargeurLameField              matlab.ui.control.NumericEditField
        paisseurcmEditFieldLabel      matlab.ui.control.Label
        EpaisseurLameField            matlab.ui.control.NumericEditField
        MassegEditFieldLabel          matlab.ui.control.Label
        MasseLameField                matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        CapteurcapacitifPanel         matlab.ui.container.Panel
        LongueurcmEditField_3Label    matlab.ui.control.Label
        LongueurCaptField             matlab.ui.control.NumericEditField
        LargeurcmEditField_3Label     matlab.ui.control.Label
        LargeurCaptField              matlab.ui.control.NumericEditField
        MatriauxDropDownLabel         matlab.ui.control.Label
        MateriauxCaptDropDown         matlab.ui.control.DropDown
        AutrePanel                    matlab.ui.container.Panel
        AmplificateurPanel            matlab.ui.container.Panel
        ActionneurlineairePanel       matlab.ui.container.Panel
        NombredespiresSpinnerLabel    matlab.ui.control.Label
        NombredespiresSpinner         matlab.ui.control.Spinner
        ArchiveTab                    matlab.ui.container.Tab
    end

    properties (Access = private)
        ScaleWidth  = 15; % Largeur initiale de la balance
        ScaleHeight = 5; % Hauteur initiale de la balance
        LameWidth   = 3; % Épaisseur de la lame
        LameNumel   = 100; % Nombre de points dans la lame
        LameInit    = 0.*linspace(0,15,100); % Vecteur de la lame initial
    end
    
    methods (Access = private)
        
        %% ENABLE/DISABLE UI FOR SIMULATION
        function disableUI(app)
            set(app.UICoinTable, 'ColumnEditable', false);
            set(app.DimensionsdelabalancePanel, 'Visible', false);
            set(app.LameflexiblePanel.Children, 'Visible', false);
            set(app.CapteurcapacitifPanel.Children, 'Visible', false);
            set(app.AutrePanel.Children, 'Visible', false);
            set(app.ActionneurlineairePanel.Children, 'Visible', false);
            set(app.AmplificateurPanel.Children, 'Visible', false);
        end
        
        function enableUI(app)
            set(app.UICoinTable, 'ColumnEditable', true);
            set(app.DimensionsdelabalancePanel, 'Visible', true);
            set(app.LameflexiblePanel.Children, 'Visible', true);
            set(app.CapteurcapacitifPanel.Children, 'Visible', true);
            set(app.AutrePanel.Children, 'Visible', true);
            set(app.ActionneurlineairePanel.Children, 'Visible', true);
            set(app.AmplificateurPanel.Children, 'Visible', true);
        end
        
        %% MANAGE COIN CONFIGURATIONS
        function addToLigneDropDown(app)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col+1);
            
            for i=1:col
                cell_array(1,i) = curr_dropdown(1,i);
            end
            cell_array(1,end) = {int2str(col+1)};

            % Add new line to dropdown
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function remFromLigneDropDown(app,line)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col-1);
            
            for i=1:col
                if cell2mat(curr_dropdown(1,i)) ~= cell2mat(curr_dropdown(1,end))
                    cell_array(1,i) = curr_dropdown(1,i);
                end
            end

            % Add new line to dropdown
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function addToCoinTable(app,currency,amount,quantity)
            % Validate if quantity provided is valid
            if quantity <= 0
                writeLogError(app,"Quantité de pièces invalide");
                return
            else
                writeNothing(app);
            end
            
            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            cell_array = cell(row+1,3);
            
            for i=1:size(curr_data,1)
                cell_array(i,:) = curr_data(i,:);
            end
            cell_array(end,:) = {currency, amount, quantity};
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            addToLigneDropDown(app);
        end
        
        function remFromCoinTable(app,line)
            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            if row == 0
                writeLogError(app,"Aucun élément dans la table");
                return
            end
            
            cell_array = cell(row-1,3);
            index = line-'0';
            
            for i=1:size(curr_data,1)
                if i < size(curr_data,1)
                   if i >= index
                       cell_array(i,:) = curr_data(i+1,:);
                   else
                       cell_array(i,:) = curr_data(i,:);
                   end
                end
            end
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            remFromLigneDropDown(app,line);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add untracked paths
            addpath('plotFunc');
            addpath('logFunc');
            addpath('UIs');
            
            setup();
            
            % Initially plot the base of the scale
            plotBase(app.UIAnim,app.ScaleWidth,app.ScaleHeight,zeros(1,15),app.LameWidth);
            plotBase(app.UIDimension,app.ScaleWidth,app.ScaleHeight,zeros(1,15),app.LameWidth);
            close all % Close remaining figures that pop-up for no reason
        end

        % Button pushed function: SimulerButton
        function SimulerButtonPushed(app, event)
            if app.SimulerButton.Text == "Stop"
                app.Lamp.Color = 'green';
                app.SimulerButton.Text = "Simuler";
                enableUI(app);
            elseif app.SimulerButton.Text == "Simuler"
                app.Lamp.Color = 'red';
                app.SimulerButton.Text = "Stop";
                disableUI(app);
                table_data = app.UICoinTable.Data;
                simuation(app,table_data);
            end
        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            addToCoinTable(app,app.DeviseDropDown.Value,app.MontantDropDown.Value,app.QuantitEditField.Value);
        end

        % Button pushed function: RetirerButton
        function RetirerButtonPushed(app, event)
            remFromCoinTable(app,app.LigneDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulateurdebalanceUIFigure = uifigure('Visible', 'off');
            app.SimulateurdebalanceUIFigure.Name = 'Simulateur de balance';
            app.TabGroup = uitabgroup(app.SimulateurdebalanceUIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAnim = uiaxes(app.SimulationTab);
            title(app.UIAnim, '')
            xlabel(app.UIAnim, '')
            ylabel(app.UIAnim, '')
            app.UIAnim.XTick = [];
            app.UIAnim.XTickLabel = '';
            app.UIAnim.YTick = [];
            app.Lamp = uilamp(app.SimulationTab);
            app.ParamtresnumismatiqueLabel = uilabel(app.SimulationTab);
            app.ParamtresnumismatiqueLabel.Text = 'Paramètres numismatique';
            app.UICoinTable = uitable(app.SimulationTab);
            app.UICoinTable.ColumnName = {'Devise'; 'Montant'; 'Quantité'};
            app.UICoinTable.RowName = {};
            app.UICoinTable.ColumnEditable = true;
            app.UIMassAction = uiaxes(app.SimulationTab);
            title(app.UIMassAction, 'Affichage de la masse')
            xlabel(app.UIMassAction, 'X')
            ylabel(app.UIMassAction, 'Y')
            app.SimulerButton = uibutton(app.SimulationTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.AddPanel = uipanel(app.SimulationTab);
            app.AjouterButton = uibutton(app.AddPanel, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Text = 'Ajouter';
            app.DeviseDropDown_2Label = uilabel(app.AddPanel);
            app.DeviseDropDown_2Label.Text = 'Devise';
            app.DeviseDropDown = uidropdown(app.AddPanel);
            app.DeviseDropDown.Items = {'CAD', 'USD'};
            app.DeviseDropDown.Value = 'CAD';
            app.MontantLabel = uilabel(app.AddPanel);
            app.MontantLabel.Text = 'Montant';
            app.MontantDropDown = uidropdown(app.AddPanel);
            app.MontantDropDown.Items = {'1¢', '5¢', '10¢', '25¢', '1$', '2$'};
            app.MontantDropDown.Value = '1¢';
            app.QuantitEditField_2Label = uilabel(app.AddPanel);
            app.QuantitEditField_2Label.Text = 'Quantité';
            app.QuantitEditField = uieditfield(app.AddPanel, 'numeric');
            app.RemPanel = uipanel(app.SimulationTab);
            app.RetirerButton = uibutton(app.RemPanel, 'push');
            app.RetirerButton.ButtonPushedFcn = createCallbackFcn(app, @RetirerButtonPushed, true);
            app.RetirerButton.Text = 'Retirer';
            app.LigneDropDownLabel = uilabel(app.RemPanel);
            app.LigneDropDownLabel.Text = 'Ligne:';
            app.LigneDropDown = uidropdown(app.RemPanel);
            app.LigneDropDown.Items = {};
            app.LigneDropDown.Value = {};
            app.LogLabel = uilabel(app.SimulationTab);
            app.LogLabel.Text = 'Log:';
            app.LogTextArea = uitextarea(app.SimulationTab);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DimensionsdelabalancePanel = uipanel(app.ConfigurationTab);
            app.DimensionsdelabalancePanel.Title = 'Dimensions de la balance';
            app.LongueurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LongueurcmEditFieldLabel.Text = 'Longueur (cm)';
            app.LongueurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LargeurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LargeurcmEditFieldLabel.Text = 'Largeur (cm)';
            app.LargeurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.HauteurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.HauteurcmEditFieldLabel.Text = 'Hauteur (cm)';
            app.HauteurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.UIDimension = uiaxes(app.DimensionsdelabalancePanel);
            title(app.UIDimension, '')
            xlabel(app.UIDimension, '')
            ylabel(app.UIDimension, '')
            app.UIDimension.XTick = [];
            app.UIDimension.XTickLabel = '';
            app.UIDimension.YTick = [];
            app.PositionduplateauSliderLabel = uilabel(app.DimensionsdelabalancePanel);
            app.PositionduplateauSliderLabel.Text = 'Position du plateau';
            app.PositionduplateauSlider = uislider(app.DimensionsdelabalancePanel);
            app.LameflexiblePanel = uipanel(app.ConfigurationTab);
            app.LameflexiblePanel.Title = 'Lame flexible';
            app.LongueurcmEditField_2Label = uilabel(app.LameflexiblePanel);
            app.LongueurcmEditField_2Label.Text = 'Longueur (cm)';
            app.LongueurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.LargeurcmEditField_2Label = uilabel(app.LameflexiblePanel);
            app.LargeurcmEditField_2Label.Text = 'Largeur (cm)';
            app.LargeurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.paisseurcmEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.paisseurcmEditFieldLabel.Text = 'Épaisseur (cm)';
            app.EpaisseurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.MassegEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.MassegEditFieldLabel.Text = 'Masse (g)';
            app.MasseLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.UIAxes = uiaxes(app.LameflexiblePanel);
            title(app.UIAxes, 'Dimensions de la lame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ZTickLabel = {'0'; '0.5'; '1'};
            app.CapteurcapacitifPanel = uipanel(app.ConfigurationTab);
            app.CapteurcapacitifPanel.Title = 'Capteur capacitif';
            app.LongueurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LongueurcmEditField_3Label.Text = 'Longueur (cm)';
            app.LongueurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.LargeurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LargeurcmEditField_3Label.Text = 'Largeur (cm)';
            app.LargeurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.MatriauxDropDownLabel = uilabel(app.CapteurcapacitifPanel);
            app.MatriauxDropDownLabel.Text = 'Matériaux';
            app.MateriauxCaptDropDown = uidropdown(app.CapteurcapacitifPanel);
            app.MateriauxCaptDropDown.Items = {'Fer', 'Aliage..'};
            app.MateriauxCaptDropDown.Value = 'Fer';
            app.AutrePanel = uipanel(app.ConfigurationTab);
            app.AutrePanel.Title = 'Autre';
            app.AmplificateurPanel = uipanel(app.ConfigurationTab);
            app.AmplificateurPanel.Title = 'Amplificateur';
            app.ActionneurlineairePanel = uipanel(app.ConfigurationTab);
            app.ActionneurlineairePanel.Title = 'Actionneur linéaire';
            app.NombredespiresSpinnerLabel = uilabel(app.ActionneurlineairePanel);
            app.NombredespiresSpinnerLabel.Text = 'Nombre de spires';
            app.NombredespiresSpinner = uispinner(app.ActionneurlineairePanel);
            app.ArchiveTab = uitab(app.TabGroup);
            app.ArchiveTab.Title = 'Archive';
            % Show the figure after all components are created
            app.SimulateurdebalanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainCAO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulateurdebalanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulateurdebalanceUIFigure)
        end
    end
end